classdef EEGWISE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch_5                        matlab.ui.control.Switch
        CalculatingLampLabel_2          matlab.ui.control.Label
        ANALYZEButton                   matlab.ui.control.Button
        CalculatingLamp                 matlab.ui.control.Lamp
        Step4ArtifactrejectbyICAButtonGroup  matlab.ui.container.ButtonGroup
        Label_2                         matlab.ui.control.Label
        ProbThresholdforArtICEditField  matlab.ui.control.NumericEditField
        ProbThresholdforArtICEditFieldLabel  matlab.ui.control.Label
        SettingsButton                  matlab.ui.control.Button
        ICADropDown                     matlab.ui.control.DropDown
        ICADropDownLabel                matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        AutomaticVisualButton_3         matlab.ui.control.RadioButton
        LabelingAlgoButton              matlab.ui.control.RadioButton
        EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel  matlab.ui.control.Label
        Step3ChannelcorrectionButtonGroup  matlab.ui.container.ButtonGroup
        Label                           matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        Switch_3                        matlab.ui.control.Switch
        SamplingrateHzEditFieldLabel_3  matlab.ui.control.Label
        AutomaticVisualButton           matlab.ui.control.RadioButton
        AutomaticbyKurtosisButton       matlab.ui.control.RadioButton
        Step1FilteringPanel             matlab.ui.container.Panel
        NotchHzDropDown                 matlab.ui.control.DropDown
        NotchHzDropDownLabel            matlab.ui.control.Label
        HighcutofffreqHzEditField       matlab.ui.control.NumericEditField
        HighcutofffreqHzEditFieldLabel  matlab.ui.control.Label
        LowcutofffreqHzEditField        matlab.ui.control.NumericEditField
        LowcutofffreqHzEditFieldLabel   matlab.ui.control.Label
        ResampingHzEditField            matlab.ui.control.NumericEditField
        ResampingHzEditFieldLabel       matlab.ui.control.Label
        InitializationPanel             matlab.ui.container.Panel
        Switch_4                        matlab.ui.control.Switch
        SamplingrateHzEditFieldLabel_4  matlab.ui.control.Label
        GenerateReportLabel             matlab.ui.control.Label
        GenerateReportSwitch            matlab.ui.control.Switch
        ResultdirectoryEditField        matlab.ui.control.EditField
        ResultdirectoryEditFieldLabel   matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        DatadirectoryEditField          matlab.ui.control.EditField
        DatadirectoryEditFieldLabel     matlab.ui.control.Label
        Step2EEGsegmentrejectionButtonGroup  matlab.ui.container.ButtonGroup
        ThresholdEditField_3            matlab.ui.control.NumericEditField
        ThresholdEditField_3Label       matlab.ui.control.Label
        AutomaticVisualButton_2         matlab.ui.control.RadioButton
        AutomaticbyASRButton            matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        selectedPath_data = ''; 
        selectedPath_results='';
        selectedPath_eeglab = '';
        % runica default settings, DEFAULT_LRATE = 0.00065/log(chans); %heuristic default, which need to be adjusted by large or small sets
        % DEFAULT_MAXSTEPS     = 512; %top training after this many steps 
        RunicaSettings struct = struct('lrate', 0.00065/log(32), 'maxSteps', 512);
        % amica default settings:
        %   'num_models'     - number of models to learn, default = 1              
        %   'num_mix_comps'  - number of mixture components in source model, def=3 
        %   'numprocs'       - number or processors (slots) to use, def=8 
        %   'maxiter'        - maximum number of iterations to perform, def=2000   
        %   'lrate'          - initial learning rate for natural gradient, def=0.1
        %   'do_newton'      - flag for newton method, default = 1 (do newton)
        %   'newt_start'     - for newton method, iter at which to start newton, def=50
        %   'writestep'      - iteration interval between output writes, def=10
        %   'do_reject'      - flag for doing rejection of time points, def=0
        %   'numrej'         - for rejection, number of rejections to perform, def=3
        %   'rejsig'         - for rejection, number of standard dev of likelihood
        %                      below which to reject data {DEFAULT??}
        %   'rejstart'       - for rejection, iteration at which to start reject, def=3
        %   'rejint'         - for rejection, iteration interval between reject, def=3
        %   'max_threads'    - maximum number of threads to use on a node, def=4
        %   'pcakeep'        - for PCA reduction, number of components to keep, def=chans
        %   'block_size'     - matrix block size (for block multiplication), def=128

        AmicaSettings struct = struct('lrate', 0.1, 'maxiter', 2000, 'num_models', 1, 'num_mix_comps', 3, 'numprocs',8,'max_threads',4);

        % This will store the current settings structure based on selection
        CurrentICASettings struct = struct();      
    end
    
    methods (Access = private)
        
        function saveSettings(app, settingsFig, selectedICA, editFields)
            % Determine correct settings property based on selected ICA method
            if strcmp(selectedICA, 'RUNICA')
                currentSettings = app.RunicaSettings;
            elseif strcmp(selectedICA, 'AMICA')
                currentSettings = app.AmicaSettings;
            else
                error('Invalid ICA method selected.');
            end
        
            % Update the settings based on edit field inputs
            for idx = 1:length(editFields)
                editField = editFields(idx);
                fieldName = editField.Tag;  % The Tag property should hold the field name
                newValue = editField.Value;  % Convert from string to appropriate data type
                currentSettings.(fieldName) = newValue;  % Update the settings directly
            end
        
            % Apply updated settings back to app properties
            if strcmp(selectedICA, 'RUNICA')
                app.RunicaSettings = currentSettings;
            elseif strcmp(selectedICA, 'AMICA')
                app.AmicaSettings = currentSettings;
            end
        
            % Close the settings window after saving
            delete(settingsFig);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default selection for ICA method
            app.ICADropDown.Value = 'RUNICA';  
            % Load settings based on default selection
            app.CurrentICASettings = app.RunicaSettings;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             app.selectedPath_data = uigetdir();
             if isfolder(app.selectedPath_data)
                app.DatadirectoryEditField.Value = [app.selectedPath_data '/'];
             end

        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
             app.selectedPath_results = uigetdir();
             if isfolder(app.selectedPath_results)
                app.ResultdirectoryEditField.Value = [app.selectedPath_results '/'];
             end
        end

        % Button pushed function: ANALYZEButton
        function ANALYZEButtonPushed(app, event)
            app.CalculatingLampLabel_2.Text= 'Calculating';
            app.CalculatingLamp.Color = 'green';
            AnalyzeEEG(app); 
            app.CalculatingLamp.Color = 'red';
            app.CalculatingLampLabel_2.Text = 'Done';
        end

        % Callback function
        function BrowseButton_3Pushed(app, event)
            app.selectedPath_eeglab = uigetdir();
             if isfolder(app.selectedPath_eeglab)
                app.EEGLABdirectoryEditField.Value = [app.selectedPath_eeglab '/'];
             end
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = app.Switch_5.Value;
            if strcmp(value, 'Autopilot')
                app.Step2EEGsegmentrejectionButtonGroup.SelectedObject= app.AutomaticbyASRButton;
                app.Step3ChannelcorrectionButtonGroup.SelectedObject= app.AutomaticbyKurtosisButton;
                app.Step4ArtifactrejectbyICAButtonGroup.SelectedObject= app.LabelingAlgoButton;
            else
                app.Step2EEGsegmentrejectionButtonGroup.SelectedObject= app.AutomaticVisualButton_2;
                app.Step3ChannelcorrectionButtonGroup.SelectedObject= app.AutomaticVisualButton;
                app.Step4ArtifactrejectbyICAButtonGroup.SelectedObject= app.AutomaticVisualButton_3;
            end
        end

        % Callback function
        function BrowseButton_3Pushed2(app, event)
            
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
             % Determine which ICA settings to use based on the dropdown selection
            selectedICA = app.ICADropDown.Value;
        
            % Create the settings figure window
            settingsFig = uifigure('Name', 'ICA Settings', 'Position', [100 100 300 250]);
        
            editFields = gobjects(0); % Array to store handles to edit fields, initialized before the loop

            % Depending on the ICA method, display different settings
            switch selectedICA
                case 'RUNICA'
                    fieldNames = fieldnames(app.RunicaSettings);
                    currentSettings = app.RunicaSettings;
                case 'AMICA'
                    fieldNames = fieldnames(app.AmicaSettings);
                    currentSettings = app.AmicaSettings;
            end
        
            % Generate dynamic UI components based on selected ICA method
            yPos = 220;  % Start position for the first label and edit field
            for idx = 1:length(fieldNames)
                fieldName = fieldNames{idx};
                fieldValue = currentSettings.(fieldName);
        
                % Create label
                uilabel(settingsFig, 'Position', [10 yPos 150 22], 'Text', fieldName);
        
                % Create edit field
                editField = uieditfield(settingsFig, 'numeric', 'Position', [160 yPos 100 22], 'Value', fieldValue);
                editField.Tag = fieldName;  % Set Tag to the field name for identification in saveSettings
                editFields(end+1) = editField;  % Store handle
        
                yPos = yPos - 30;
            end
        
            % Create Save button
            saveBtn = uibutton(settingsFig, 'push', 'Text', 'Save Settings', 'Position', [100 10 100 22]);
            saveBtn.ButtonPushedFcn = @(btn, event) app.saveSettings(settingsFig, selectedICA, editFields);

                
        end

        % Value changed function: ICADropDown
        function ICADropDownValueChanged(app, event)
            method = app.ICADropDown.Value;
            switch method
                case 'RUNICA'
                    app.CurrentICASettings = app.RunicaSettings;
                case 'AMICA'
                    app.CurrentICASettings = app.AmicaSettings;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step2EEGsegmentrejectionButtonGroup = uibuttongroup(app.UIFigure);
            app.Step2EEGsegmentrejectionButtonGroup.Title = 'Step 2. EEG segment rejection';
            app.AutomaticbyASRButton = uiradiobutton(app.Step2EEGsegmentrejectionButtonGroup);
            app.AutomaticbyASRButton.Tooltip = {'Cutoff : Standard deviation cutoff for removal of bursts (via ASR). Data portions whose variance is larger than this threshold relative to the calibration data are considered missing data and will be removed. The most aggressive value that can be used without losing much EEG is 3. For new users it is recommended to at first visually inspect the difference between the original and cleaned data to get a sense of the removed content at various levels. A quite conservative value is 5. Default: 5.'};
            app.AutomaticbyASRButton.Text = 'Automatic by ASR';
            app.AutomaticbyASRButton.Value = true;
            app.AutomaticVisualButton_2 = uiradiobutton(app.Step2EEGsegmentrejectionButtonGroup);
            app.AutomaticVisualButton_2.Text = 'Automatic + Visual';
            app.ThresholdEditField_3Label = uilabel(app.Step2EEGsegmentrejectionButtonGroup);
            app.ThresholdEditField_3Label.Text = '-- Threshold';
            app.ThresholdEditField_3 = uieditfield(app.Step2EEGsegmentrejectionButtonGroup, 'numeric');
            app.ThresholdEditField_3.Tooltip = {'Cutoff : Standard deviation cutoff for removal of bursts (via ASR). Data portions whose variance is larger than this threshold relative to the calibration data are considered missing data and will be removed. The most aggressive value that can be used without losing much EEG is 3. For new users it is recommended to at first visually inspect the difference between the original and cleaned data to get a sense of the removed content at various levels. A quite conservative value is 5. Default: 5.'};
            app.ThresholdEditField_3.Value = 20;
            app.InitializationPanel = uipanel(app.UIFigure);
            app.InitializationPanel.Title = 'Initialization';
            app.DatadirectoryEditFieldLabel = uilabel(app.InitializationPanel);
            app.DatadirectoryEditFieldLabel.Text = 'Data directory';
            app.DatadirectoryEditField = uieditfield(app.InitializationPanel, 'text');
            app.DatadirectoryEditField.Value = './Data/';
            app.BrowseButton = uibutton(app.InitializationPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.InitializationPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.ResultdirectoryEditFieldLabel = uilabel(app.InitializationPanel);
            app.ResultdirectoryEditFieldLabel.Text = 'Result directory';
            app.ResultdirectoryEditField = uieditfield(app.InitializationPanel, 'text');
            app.ResultdirectoryEditField.Value = './Results/';
            app.GenerateReportSwitch = uiswitch(app.InitializationPanel, 'slider');
            app.GenerateReportSwitch.Items = {'Yes', 'No'};
            app.GenerateReportSwitch.Value = 'Yes';
            app.GenerateReportLabel = uilabel(app.InitializationPanel);
            app.GenerateReportLabel.Text = 'Generate report';
            app.SamplingrateHzEditFieldLabel_4 = uilabel(app.InitializationPanel);
            app.SamplingrateHzEditFieldLabel_4.Text = 'PSD Plot';
            app.Switch_4 = uiswitch(app.InitializationPanel, 'slider');
            app.Switch_4.Items = {'Yes', 'No'};
            app.Switch_4.Value = 'Yes';
            app.Step1FilteringPanel = uipanel(app.UIFigure);
            app.Step1FilteringPanel.Title = 'Step 1. Filtering';
            app.ResampingHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.ResampingHzEditFieldLabel.Text = 'Resamping (Hz)';
            app.ResampingHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.ResampingHzEditField.Value = 250;
            app.LowcutofffreqHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.LowcutofffreqHzEditFieldLabel.Text = 'Low cutoff freq (Hz)';
            app.LowcutofffreqHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.LowcutofffreqHzEditField.Value = 0.2;
            app.HighcutofffreqHzEditFieldLabel = uilabel(app.Step1FilteringPanel);
            app.HighcutofffreqHzEditFieldLabel.Text = 'High cutoff freq (Hz)';
            app.HighcutofffreqHzEditField = uieditfield(app.Step1FilteringPanel, 'numeric');
            app.HighcutofffreqHzEditField.Value = 100;
            app.NotchHzDropDownLabel = uilabel(app.Step1FilteringPanel);
            app.NotchHzDropDownLabel.Text = 'Notch (Hz)';
            app.NotchHzDropDown = uidropdown(app.Step1FilteringPanel);
            app.NotchHzDropDown.Items = {'60', '50'};
            app.NotchHzDropDown.ItemsData = [60 50];
            app.NotchHzDropDown.Value = 60;
            app.Step3ChannelcorrectionButtonGroup = uibuttongroup(app.UIFigure);
            app.Step3ChannelcorrectionButtonGroup.Title = 'Step 3. Channel correction';
            app.AutomaticbyKurtosisButton = uiradiobutton(app.Step3ChannelcorrectionButtonGroup);
            app.AutomaticbyKurtosisButton.Text = 'Automatic by Kurtosis ';
            app.AutomaticbyKurtosisButton.Value = true;
            app.AutomaticVisualButton = uiradiobutton(app.Step3ChannelcorrectionButtonGroup);
            app.AutomaticVisualButton.Text = 'Automatic + Visual';
            app.SamplingrateHzEditFieldLabel_3 = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.SamplingrateHzEditFieldLabel_3.Text = 'Spatial filtering by CAR';
            app.Switch_3 = uiswitch(app.Step3ChannelcorrectionButtonGroup, 'slider');
            app.Switch_3.Items = {'Yes', 'No'};
            app.Switch_3.Value = 'Yes';
            app.ThresholdEditFieldLabel = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Step3ChannelcorrectionButtonGroup, 'numeric');
            app.ThresholdEditField.Value = 5;
            app.Label = uilabel(app.Step3ChannelcorrectionButtonGroup);
            app.Label.Text = '--';
            app.EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel = uilabel(app.UIFigure);
            app.EEGWISEEEGWorkflowImprovementandSignalEnhancementToolboxLabel.Text = 'EEGWISE - EEG Workflow Improvement and Signal Enhancement Toolbox';
            app.Step4ArtifactrejectbyICAButtonGroup = uibuttongroup(app.UIFigure);
            app.Step4ArtifactrejectbyICAButtonGroup.Title = 'Step 4. Artifact reject by ICA';
            app.LabelingAlgoButton = uiradiobutton(app.Step4ArtifactrejectbyICAButtonGroup);
            app.LabelingAlgoButton.Text = 'Labeling Algo';
            app.LabelingAlgoButton.Value = true;
            app.AutomaticVisualButton_3 = uiradiobutton(app.Step4ArtifactrejectbyICAButtonGroup);
            app.AutomaticVisualButton_3.Text = 'Automatic + Visual';
            app.DropDown = uidropdown(app.Step4ArtifactrejectbyICAButtonGroup);
            app.DropDown.Items = {'iMARA', 'MARA', 'ICLabels', 'Ensemble'};
            app.DropDown.Value = 'iMARA';
            app.ICADropDownLabel = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.ICADropDownLabel.Text = 'ICA';
            app.ICADropDown = uidropdown(app.Step4ArtifactrejectbyICAButtonGroup);
            app.ICADropDown.Items = {'RUNICA', 'AMICA'};
            app.ICADropDown.ValueChangedFcn = createCallbackFcn(app, @ICADropDownValueChanged, true);
            app.ICADropDown.Value = 'RUNICA';
            app.SettingsButton = uibutton(app.Step4ArtifactrejectbyICAButtonGroup, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.ProbThresholdforArtICEditFieldLabel = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.ProbThresholdforArtICEditFieldLabel.Tooltip = {'Leave as 0 for automatic probability cutoff for artifictual IC labeling, or set a value between 0 and 1 '};
            app.ProbThresholdforArtICEditFieldLabel.Text = 'Prob. Threshold for Art. IC';
            app.ProbThresholdforArtICEditField = uieditfield(app.Step4ArtifactrejectbyICAButtonGroup, 'numeric');
            app.ProbThresholdforArtICEditField.Limits = [0 1];
            app.ProbThresholdforArtICEditField.Tooltip = {'Leave as 0 for automatic probability cutoff for artifictual IC labeling, or set a value between 0 and 1 '};
            app.Label_2 = uilabel(app.Step4ArtifactrejectbyICAButtonGroup);
            app.Label_2.Text = '--';
            app.CalculatingLamp = uilamp(app.UIFigure);
            app.ANALYZEButton = uibutton(app.UIFigure, 'push');
            app.ANALYZEButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYZEButtonPushed, true);
            app.ANALYZEButton.Text = 'ANALYZE';
            app.CalculatingLampLabel_2 = uilabel(app.UIFigure);
            app.CalculatingLampLabel_2.Text = '';
            app.Switch_5 = uiswitch(app.UIFigure, 'slider');
            app.Switch_5.Items = {'Autopilot', 'Hybrid'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = 'Autopilot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGWISE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end